classdef bmsMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PrimaryTab                   matlab.ui.container.Tab
        TempTable                    matlab.ui.control.Table
        VoltageTable                 matlab.ui.control.Table
        Panel                        matlab.ui.container.Panel
        ErrorLabel                   matlab.ui.control.Label
        ChargerStatusLabel           matlab.ui.control.Label
        ICMaxTempLabel               matlab.ui.control.Label
        ChargerCurrentLabel          matlab.ui.control.Label
        TSCurrentLabel               matlab.ui.control.Label
        MaxTempLabel                 matlab.ui.control.Label
        MinTempLabel                 matlab.ui.control.Label
        DeltaVoltLabel               matlab.ui.control.Label
        MaxVoltLabel                 matlab.ui.control.Label
        MinVoltLabel                 matlab.ui.control.Label
        AvgVoltLabel                 matlab.ui.control.Label
        PackVoltLabel                matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        LogCSVCheckBox               matlab.ui.control.CheckBox
        FloatCheckBox                matlab.ui.control.CheckBox
        BalanceCheckBox              matlab.ui.control.CheckBox
        ChargeCurrentFieldEditField  matlab.ui.control.NumericEditField
        ChargeCurrentFieldEditFieldLabel  matlab.ui.control.Label
        BaudDropdownDropDown         matlab.ui.control.DropDown
        BaudDropdownDropDownLabel    matlab.ui.control.Label
        PortField                    matlab.ui.control.EditField
        PortFieldEditFieldLabel      matlab.ui.control.Label
        ChargeButton                 matlab.ui.control.StateButton
        ConnectButton                matlab.ui.control.StateButton
        SummaryTab                   matlab.ui.container.Tab
        ICTempTable                  matlab.ui.control.Table
        SegmentTable                 matlab.ui.control.Table
    end

    
  properties (Access = private)
   ser                 % serial port
    readTimer           % timer για ανάγνωση CAN
    guiTimer            % timer για ενημέρωση GUI

    connected logical = false

    VOLTAGES    double   % 12x12 cell voltages
    TEMPS       double   % 12x12 temperatures
    MAXICTemps  double   % 12 IC temp values
    dischFlags  double   % 12x12 flags (0/1) για discharge (μελλοντικά)
    MAX_VOLTAGE_THRESHOLD double = 4.15;   % V  (όριο για stop charge)
    MIN_VOLTAGE_THRESHOLD double = 2.7;    % V  (κάτω όριο για stop)
    TEMP_WARN_LIMIT      double = 59;      % °C (όριο θερμοκρασίας)
    lastRxTime double = 0
    CAN_TIMEOUT double = 3.0
    lostConFlag double = 0

    hbTimer             % timer για heartbeat προς BMS/CANdapter
    csvFile char = 'bmsLoggingData.csv'
    csvFID double = -1          % file id για fopen (>=0 σημαίνει ανοιχτό)
    csvHeaderWritten logical = false

    ivtCurrent double = 0        % mA από IVT
    chargerCurrent double = 0    % A από φορτιστή
    chargerStatusFlag double = -1
    chargerConnected logical = false

    chargingState double = 0   % 0=OFF, 1=ON
    strHex char = '64'         % default current HEX

    TEMP_SENSOR_MAP = [1 2 3 4 5 6 7 8 9 11];
    lastError char = 'None';   % για τα error codes του BMS
  end
    
      % App initialization
    methods (Access = private)

        function startupFcn(app)
           % ===== Αρχικοποίηση πινάκων BMS =====
        app.VOLTAGES   = -1  * ones(12,12);   % άγνωστες τάσεις
        app.TEMPS      = -40 * ones(12,12);   % αρχικές θερμοκρασίες
        app.MAXICTemps = zeros(1,12);         % 12 IC temps
        app.dischFlags = zeros(12,12);        % όλα τα κελιά: όχι σε discharge

        % ===== Κατάσταση σύνδεσης / φορτιστή =====
        app.connected         = false;
        app.chargerConnected  = false;
        app.chargingState     = 0;            % 0 = OFF
        app.ivtCurrent        = 0;
        app.chargerCurrent    = 0;
        app.chargerStatusFlag = -1;
        app.strHex            = '64';         % default hex
        app.lastError         = 'None';

        % ===== Αρχικά μηνύματα GUI =====
        app.StatusLabel.Text = "Disconnected";
        app.ErrorLabel.Text  = "";

        % ===== Γέμισε τα tables με αρχικά δεδομένα =====
        app.VoltageTable.Data = app.VOLTAGES;
        app.TempTable.Data    = app.TEMPS;

        % Summary tables 
        app.SegmentTable.Data = zeros(1,12);
        app.ICTempTable.Data  = zeros(1,12);
        app.ConnectButton.ValueChangedFcn = @(src,event) ConnectButtonValueChanged(app,event);
        app.TempTable.CellEditCallback   = @(src,event) TempTableCellEdit(app,event);
        app.ChargeButton.ValueChangedFcn = @(src,event) ChargeButtonValueChanged(app,event);  
        app.LogCSVCheckBox.ValueChangedFcn = @(src,event) LogCSVCheckBoxValueChanged(app,event);


        % ======================================================
        %   TIMERS
        % ======================================================
        app.readTimer = timer( ...
            "ExecutionMode", "fixedSpacing", ...
            "Period", 0.02, ...             % 20ms
            "TimerFcn", @(~,~) serialReadTick(app) );

        app.guiTimer = timer( ...
            "ExecutionMode", "fixedSpacing", ...
            "Period", 0.10, ...             % 10Hz
            "TimerFcn", @(~,~) guiTick(app) ); 
     app.lastRxTime = tic;

    % Heartbeat timer 
    app.hbTimer = timer( ...
        "ExecutionMode","fixedSpacing", ...
        "Period", 0.2, ...                 
        "TimerFcn", @(~,~) heartbeatTick(app) );

            
 end
    function startupFcn2(app)
        startupFcn(app);
    end
        
     
      % ======================================================
    %   GUI UPDATE FUNCTION
    % ======================================================
         % ======================================================
        %   SERIAL READ TICK
        % ======================================================
function serialReadTick(app)

    if ~app.connected
        return;
    end

    try
        while app.ser.NumBytesAvailable > 0
            line = readline(app.ser);
            line = strtrim(line);

            if line == ""
                continue;
            end

            % ===== SLCAN frames =====
            if startsWith(line,"t") || startsWith(line,"T")
                [ok, ~, canId, data] = parseSlcanLine(app, line);
                if ok
                    app.lastRxTime = tic;
                    decodeCanFrame(app, canId, data);
                end
                continue;
            end

            % ===== CSV-like messages =====
            parts = split(line, ",");
            if numel(parts) < 2
                continue;
            end

            prefix = parts{1};

            switch prefix

                case "V"
                    if numel(parts) < 4, continue; end
                    seg = str2double(parts{2});
                    cell = str2double(parts{3});
                    val = str2double(parts{4});
                    if seg>=1 && seg<=12 && cell>=1 && cell<=12
                        app.VOLTAGES(seg,cell) = val;
                    end

                case "T"
                    if numel(parts) < 4, continue; end
                    seg = str2double(parts{2});
                    cell = str2double(parts{3});
                    val = str2double(parts{4});
                    if seg>=1 && seg<=12 && cell>=1 && cell<=12
                        app.TEMPS(seg,cell) = val;
                    end

                case "IC"
                    ic = str2double(parts{2});
                    val = str2double(parts{3});
                    if ic>=1 && ic<=12
                        app.MAXICTemps(ic) = val;
                    end

                case "IVT"
                    app.ivtCurrent = str2double(parts{2});

                case "CC"
                    app.chargerCurrent = str2double(parts{2});

                case "CH"
                    app.chargerConnected = logical(str2double(parts{2}));
                    app.chargerStatusFlag = str2double(parts{3});

                case "ERR"
                    code = str2double(parts{2});
                    switch code
                        case 0, app.lastError = "ISOSPI";
                        case 1, app.lastError = "Voltages";
                        case 2, app.lastError = "Temps";
                        case 3, app.lastError = "Current Sensor";
                        otherwise, app.lastError = "Generic";
                    end
                    app.ErrorLabel.Text = "Last Error: " + app.lastError;
            end
        end

        app.safetyCheck();

        % ===== Timeout =====
        if toc(app.lastRxTime) > app.CAN_TIMEOUT
            app.StatusLabel.Text = "Disconnected (timeout)";
            app.ConnectButton.Value = 0;
            app.connected = false;
        end

    catch ME
        app.ErrorLabel.Text = ME.message;
    end
end        

function heartbeatTick(app)

    if ~app.connected || isempty(app.ser)
        return;
    end

    try
        balanceFlag = app.BalanceCheckBox.Value;
        on_off = '1';
        if app.chargingState == 1
            on_off = '0';
        end

        hb = sprintf("T1502%s%d%s", app.strHex, balanceFlag, on_off);
        writeline(app.ser, hb);
        writeline(app.ser, "L1");

    catch
        
    end
end
function [ok, isExt, canId, dataBytes] = parseSlcanLine(app, rawLine)

    ok = false; isExt = false; canId = 0; dataBytes = uint8([]);

    s = char(rawLine);

    if s(1) == 't'
        canId = hex2dec(s(2:4));
        dlc   = hex2dec(s(5));
        dataHex = s(6:5+2*dlc);
    elseif s(1) == 'T'
        isExt = true;
        canId = hex2dec(s(2:9));
        dlc   = hex2dec(s(10));
        dataHex = s(11:10+2*dlc);
    else
        return;
    end

    dataBytes = uint8(zeros(1,dlc));
    for i = 1:dlc
        dataBytes(i) = uint8(hex2dec(dataHex(2*i-1:2*i)));
    end

    ok = true;
end
function decodeCanFrame(app, canId, data)

    switch canId

        case hex2dec('700')
            ic   = bitand(data(1),15) + 1;
            base = bitand(data(2),15) + 1;

            for i = 0:2
                idx = mod(base-1+i,12)+1;
                raw = int16(typecast(uint8(data(3+2*i:4+2*i)),'uint16'));
                app.VOLTAGES(ic,idx) = (double(raw)+10000)*0.000150;
            end

        case hex2dec('701')
            ic = bitand(data(1),15)+1;
            app.MAXICTemps(ic) = bitor(bitshift(data(1),-4)*16, bitshift(data(2),-4));

        case hex2dec('521')
            raw = typecast(uint8(data(5:8)),'int32');
            app.ivtCurrent = double(raw);

        case hex2dec('102')
            app.chargerCurrent = double(data(1))/10;

        case hex2dec('101')
            app.lastError = "BMS Fault";
            app.ErrorLabel.Text = "Last Error: BMS Fault";
    end
end
function LogCSVCheckBoxValueChanged(app, event)
    if app.LogCSVCheckBox.Value
        openCsv(app);
    else
        closeCsv(app);
    end
end
function openCsv(app)

    % αν είναι ήδη ανοιχτό, μην το ξανανοίγεις
    if app.csvFID >= 0
        return;
    end

    path = fullfile(pwd, app.csvFile);

    % Αν δεν υπάρχει, θα γραφτεί header
    newFile = ~isfile(path);

    [fid, msg] = fopen(path, "a");
    if fid < 0
        app.ErrorLabel.Text = "CSV open failed: " + string(msg);
        app.LogCSVCheckBox.Value = 0;
        return;
    end

    app.csvFID = fid;

    if newFile
        fprintf(app.csvFID, "timestamp,packV,minV,maxV,avgV,deltaV,minT,maxT,ivtA,chargerA,chargerConn,chargerStatus,lastError\n");
        app.csvHeaderWritten = true;
    end
end
function closeCsv(app)
    try
        if app.csvFID >= 0
            fclose(app.csvFID);
        end
    catch
    end

    app.csvFID = -1;
end
function logCsvRow(app)
    % Γράφει 1 γραμμή στο CSV όταν είναι ενεργό το checkbox

    if ~app.LogCSVCheckBox.Value
        return;
    end

    % Header write once (αν δεν υπάρχει αρχείο)
    fileExists = isfile(app.csvFile);
    fid = fopen(app.csvFile, 'a');
    if fid < 0
        return;
    end

    cleaner = onCleanup(@() fclose(fid));

    if ~fileExists
        fprintf(fid, "t,packV,minV,maxV,deltaV,minT,maxT,ivtA,chgA,chgConn,chgFlag,lastErr\n");
    end

    voltAll = app.VOLTAGES(app.VOLTAGES > 0);
    tempAll = app.TEMPS(app.TEMPS > -40);

    packV  = NaN; minV = NaN; maxV = NaN; deltaV = NaN;
    minT   = NaN; maxT = NaN;

    if ~isempty(voltAll)
        packV  = sum(voltAll);
        minV   = min(voltAll);
        maxV   = max(voltAll);
        deltaV = maxV - minV;
    end

    if ~isempty(tempAll)
        minT = min(tempAll);
        maxT = max(tempAll);
    end

    ivtA = app.ivtCurrent / 1000.0;   % mA -> A
    chgA = app.chargerCurrent;

    fprintf(fid, "%.3f,%.3f,%.3f,%.3f,%.3f,%.1f,%.1f,%.3f,%.3f,%d,%d,%s\n", ...
        toc(app.lastRxTime), packV, minV, maxV, deltaV, minT, maxT, ivtA, chgA, ...
        app.chargerConnected, app.chargerStatusFlag, string(app.lastError));
end

        % ======================================================
        %   GUI UPDATE FUNCTION
        % ======================================================
        function guiTick(app)
            try
                % ===== Tables =====
                app.VoltageTable.Data = app.VOLTAGES;
                app.TempTable.Data    = app.TEMPS;

                % ===== Υπολογισμοί τάσεων =====
                voltAll = app.VOLTAGES(app.VOLTAGES > 0);
                tempAll = app.TEMPS(app.TEMPS > -40);

                if ~isempty(voltAll)
                    packV = sum(voltAll);
                    app.PackVoltLabel.Text = sprintf("Pack: %.2f V", packV);

                    app.MinVoltLabel.Text  = sprintf("Min: %.3f", min(voltAll));
                    app.MaxVoltLabel.Text  = sprintf("Max: %.3f", max(voltAll));
                    app.AvgVoltLabel.Text  = sprintf("Avg: %.3f", mean(voltAll));
                    app.DeltaVoltLabel.Text = sprintf("Δ: %.3f", max(voltAll)-min(voltAll));
                end

                % ===== Υπολογισμοί θερμοκρασιών =====
                if ~isempty(tempAll)
                    app.MinTempLabel.Text = sprintf("MinT: %.1f", min(tempAll));
                    app.MaxTempLabel.Text = sprintf("MaxT: %.1f", max(tempAll));
                end

                % ===== Summary tables =====
                app.SegmentTable.Data = mean(app.VOLTAGES,2,'omitnan')';
                app.ICTempTable.Data  = app.MAXICTemps;

                % ===== TS Current / Charger Current =====
                app.TSCurrentLabel.Text = sprintf("TS Current: %.2f A", app.ivtCurrent/1000);   % mA -> A
                app.ChargerCurrentLabel.Text = sprintf("Charger Current: %.1f A", app.chargerCurrent);

                % ===== Max IC Temp summary =====
                maxICT = max(app.MAXICTemps);
                if maxICT > 0
                    app.ICMaxTempLabel.Text = sprintf("Max IC Temp: %.1f °C", maxICT);
                else
                    app.ICMaxTempLabel.Text = "Max IC Temp: -- °C";
                end

                % ===== Charger status text =====
                if ~app.connected
                    chargerTxt = "Charger status: --";
                else
                    if app.chargerStatusFlag == -1
                        chargerTxt = "Charger status: Unknown";
                    elseif app.chargerStatusFlag == 0
                        if app.chargerConnected
                            chargerTxt = "Charger status: OK";
                        else
                            chargerTxt = "Charger status: Disconnected";
                        end
                    elseif app.chargerStatusFlag == 1
                        chargerTxt = "Charger status: No DC Link";
                    else
                        chargerTxt = "Charger status: Fault";
                    end
                end
                app.ChargerStatusLabel.Text = chargerTxt;
                logCsvRow(app);

            catch
                
            end
        end
                % ======================================================
        %   SAFETY CHECK (όρια τάσης / θερμοκρασίας)
        % ======================================================
        function safetyCheck(app)
            if ~app.connected
                return;
            end

            % Όλες οι έγκυρες τάσεις / θερμοκρασίες
            voltAll = app.VOLTAGES(app.VOLTAGES > 0);
            tempAll = app.TEMPS(app.TEMPS > -40);

            overV  = ~isempty(voltAll) && max(voltAll) > app.MAX_VOLTAGE_THRESHOLD;
            underV = ~isempty(voltAll) && min(voltAll) < app.MIN_VOLTAGE_THRESHOLD;
            overT  = ~isempty(tempAll) && max(tempAll) > app.TEMP_WARN_LIMIT;

            if app.chargingState == 1 && (overV || underV || overT)
                % ===== ΣΤΑΜΑΤΑ ΦΟΡΤΙΣΗ =====
                app.chargingState = 0;

                % Προσπάθησε να στείλεις CAN frame για OFF
                try
                    if ~isempty(app.ser)
                        balanceFlag = app.BalanceCheckBox.Value;
                        on_off = '1';  % 1 => OFF (όπως στο Python)
                        cmdOff = sprintf("T1502%s%d%s", app.strHex, balanceFlag, on_off);
                        writeline(app.ser, cmdOff);
                        writeline(app.ser, "L1");
                    end
                catch
                   
                end

                % Λόγος διακοπής
                if overV
                    reason = "Cell over-voltage";
                elseif underV
                    reason = "Cell under-voltage";
                else
                    reason = "Cell over-temperature";
                end

                app.ErrorLabel.Text  = "Charge stopped (safety): " + reason;
                app.StatusLabel.Text = "Charging OFF (safety)";

                % Γύρνα και το κουμπί Charge σε OFF αν υπάρχει
                if isvalid(app.ChargeButton)
                    app.ChargeButton.Value = 0;
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn3(app)
          % αρχικό status
app.StatusLabel.Text = "Disconnected";
app.ErrorLabel.Text  = "";

% αρχικά δεδομένα tables
app.VoltageTable.Data = app.VOLTAGES;
app.TempTable.Data    = app.TEMPS;
app.SegmentTable.Data = zeros(1,12);
app.ICTempTable.Data  = zeros(1,12);

% reset flags
app.connected = false;
app.chargingState = 0;
  
        end

        % Cell edit callback: TempTable
        function TempTableCellEdit(app, event)
          indices = event.Indices;   % [row, col]
    newValue = event.NewData;

    row = indices(1);
    col = indices(2);

    % Αποθήκευση της νέας τιμής στο matrix TEMPS
    if row >= 1 && row <= 12 && col >= 1 && col <= 12
        app.TEMPS(row, col) = newValue;
    end

    % Προαιρετικό: ενημέρωση GUI αμέσως μετά την αλλαγή
    app.TempTable.Data = app.TEMPS; 
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
        value = app.ConnectButton.Value;
            if value == 1
                % ===== ΣΥΝΔΕΣΗ =====
                try
                    port = strtrim(app.PortField.Value);
                    if isempty(port)
                        app.StatusLabel.Text = "No COM port";
                        app.ConnectButton.Value = 0;
                        return;
                    end

                    baud_uart = 9600;
                    app.ser = serialport(port, baud_uart, "Timeout", 0.1);
                    configureTerminator(app.ser, "CR");
                    flush(app.ser);

                    % Stop + timestamps
                    writeline(app.ser, "C");
                    writeline(app.ser, "L1");
                    pause(0.2);

                    % CAN bitrate
                    switch app.BaudDropdownDropDown.Value
                        case "125kbit/s"
                            can_cmd = "S4";
                        case "250kbit/s"
                            can_cmd = "S5";
                        case "500kbit/s"
                            can_cmd = "S6";
                        case "1000kbit/s"
                            can_cmd = "S8";
                        otherwise
                            can_cmd = "S6";
                    end

                    writeline(app.ser, can_cmd);
                    writeline(app.ser, "L1");
                    pause(0.2);

                    % Open CAN
                    writeline(app.ser, "O");
                    writeline(app.ser, "L1");
                    pause(0.2);

                    % Αρχικό frame προς BMS
                    balanceFlag = app.BalanceCheckBox.Value;
                    init = sprintf("T1502%s%d1", app.strHex, balanceFlag);
                    writeline(app.ser, init);
                    writeline(app.ser, "L1");

                    % Timers
                    start(app.readTimer);
                    if strcmp(app.guiTimer.Running, "off")
                        start(app.guiTimer);
                    end
                    if strcmp(app.hbTimer.Running, "off")
                       start(app.hbTimer);
                   end


                    app.connected = true;
                    app.StatusLabel.Text = "Connected";

                catch ME
                    app.connected = false;
                    app.StatusLabel.Text = "Serial Error";
                    app.ErrorLabel.Text  = ME.message;
                    app.ConnectButton.Value = 0;
                    try
                        if ~isempty(app.ser)
                            clear app.ser
                        end
                    end
                end

            else
                % ===== ΑΠΟΣΥΝΔΕΣΗ =====
                app.connected = false;

                if strcmp(app.readTimer.Running,"on")
                    stop(app.readTimer);
                end
                if strcmp(app.hbTimer.Running,"on")
                   stop(app.hbTimer);
                end

                try
                    if ~isempty(app.ser)
                        clear app.ser
                    end
                end

                app.StatusLabel.Text = "Disconnected";
            end
        end

        % ChargeButton: ξεκινά / σταματά φόρτιση
        function ChargeButtonValueChanged(app, event)

            if ~app.connected
                app.ErrorLabel.Text = "Not connected";
                app.ChargeButton.Value = 0;
                return;
            end

            curr = app.ChargeCurrentFieldEditField.Value;

            if isnan(curr) || curr <= 0 || curr > 6
                app.ErrorLabel.Text = "Charge current must be 0–6 A";
                app.ChargeButton.Value = 0;
                return;
            end

            balanceFlag = app.BalanceCheckBox.Value;

            if app.ChargeButton.Value == 1
                % ===== ΕΝΑΡΞΗ ΦΟΡΤΙΣΗΣ =====
                app.chargingState = 1;

                rawVal     = int32(curr * 10) + 100;   % ίδιο mapping με Python
                app.strHex = upper(dec2hex(rawVal, 2));
                on_off     = '0';   % 0 => ON

                cmd = sprintf("T1502%s%d%s", app.strHex, balanceFlag, on_off);

                try
                    writeline(app.ser, cmd);
                    writeline(app.ser, "L1");
                    app.StatusLabel.Text = sprintf("Charging %.1f A", curr);
                    app.ErrorLabel.Text  = "";
                catch ME
                    app.ErrorLabel.Text    = ME.message;
                    app.StatusLabel.Text   = "Charge cmd error";
                    app.ChargeButton.Value = 0;
                    app.chargingState      = 0;
                end
            else
                % ===== ΣΤΑΜΑΤΗΜΑ ΦΟΡΤΙΣΗΣ (από χρήστη) =====
                app.chargingState = 0;
                on_off = '1';   % 1 => OFF
                cmd = sprintf("T1502%s%d%s", app.strHex, balanceFlag, on_off);

                try
                    writeline(app.ser, cmd);
                    writeline(app.ser, "L1");
                    app.StatusLabel.Text = "Charging OFF";
                catch ME
                    app.ErrorLabel.Text  = ME.message;
                    app.StatusLabel.Text = "Charge cmd error";
                end
            end
       
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        

    % ===== 1) Σταμάτησε & διέγραψε timers =====
    try
        if ~isempty(app.readTimer) && isvalid(app.readTimer)
            stop(app.readTimer);
            delete(app.readTimer);
        end
    end

    try
        if ~isempty(app.guiTimer) && isvalid(app.guiTimer)
            stop(app.guiTimer);
            delete(app.guiTimer);
        end
    end

    try
        if ~isempty(app.hbTimer) && isvalid(app.hbTimer)
            stop(app.hbTimer);
            delete(app.hbTimer);
        end
    end

    % ===== 2) Κλείσε serial =====
    try
        if ~isempty(app.ser)
            clear app.ser
        end
    end

    % ===== 3) Κλείσε το app (ΤΕΛΟΣ) =====
    closeCsv(app);

    delete(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PrimaryTab = uitab(app.TabGroup);
            app.PrimaryTab.Title = 'PrimaryTab';
            app.Panel = uipanel(app.PrimaryTab);
            app.ConnectButton = uibutton(app.Panel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = {'Connect'; ''};
            app.ChargeButton = uibutton(app.Panel, 'state');
            app.ChargeButton.Text = {'Charge'; ''};
            app.PortFieldEditFieldLabel = uilabel(app.Panel);
            app.PortFieldEditFieldLabel.Text = 'PortField';
            app.PortField = uieditfield(app.Panel, 'text');
            app.BaudDropdownDropDownLabel = uilabel(app.Panel);
            app.BaudDropdownDropDownLabel.Text = 'BaudDropdown';
            app.BaudDropdownDropDown = uidropdown(app.Panel);
            app.BaudDropdownDropDown.Items = {'125kbit/s', '250kbit/s', '500kbit/s', '1000kbit/s', ''};
            app.BaudDropdownDropDown.Value = '125kbit/s';
            app.ChargeCurrentFieldEditFieldLabel = uilabel(app.Panel);
            app.ChargeCurrentFieldEditFieldLabel.Text = 'ChargeCurrentField';
            app.ChargeCurrentFieldEditField = uieditfield(app.Panel, 'numeric');
            app.BalanceCheckBox = uicheckbox(app.Panel);
            app.BalanceCheckBox.Text = {'Balance'; ''};
            app.FloatCheckBox = uicheckbox(app.Panel);
            app.FloatCheckBox.Text = 'Float';
            app.LogCSVCheckBox = uicheckbox(app.Panel);
            app.LogCSVCheckBox.Text = 'Log CSV';
            app.StatusLabel = uilabel(app.Panel);
            app.StatusLabel.Text = 'StatusLabel';
            app.PackVoltLabel = uilabel(app.Panel);
            app.PackVoltLabel.Text = 'PackVoltLabel';
            app.AvgVoltLabel = uilabel(app.Panel);
            app.AvgVoltLabel.Text = 'AvgVoltLabel';
            app.MinVoltLabel = uilabel(app.Panel);
            app.MinVoltLabel.Text = 'MinVoltLabel';
            app.MaxVoltLabel = uilabel(app.Panel);
            app.MaxVoltLabel.Text = 'MaxVoltLabel';
            app.DeltaVoltLabel = uilabel(app.Panel);
            app.DeltaVoltLabel.Text = 'DeltaVoltLabel';
            app.MinTempLabel = uilabel(app.Panel);
            app.MinTempLabel.Text = 'MinTempLabel';
            app.MaxTempLabel = uilabel(app.Panel);
            app.MaxTempLabel.Text = 'MaxTempLabel';
            app.TSCurrentLabel = uilabel(app.Panel);
            app.TSCurrentLabel.Text = 'TSCurrentLabel';
            app.ChargerCurrentLabel = uilabel(app.Panel);
            app.ChargerCurrentLabel.Text = 'ChargerCurrentLabel';
            app.ICMaxTempLabel = uilabel(app.Panel);
            app.ICMaxTempLabel.Text = 'ICMaxTempLabel';
            app.ChargerStatusLabel = uilabel(app.Panel);
            app.ChargerStatusLabel.Text = 'ChargerStatusLabel';
            app.ErrorLabel = uilabel(app.Panel);
            app.ErrorLabel.Text = 'ErrorLabel';
            app.VoltageTable = uitable(app.PrimaryTab);
            app.VoltageTable.ColumnName = {'cell1'; 'cell2'; 'cell3'; 'cell4'; 'cell5'; 'cell6'; 'cell7'; 'cell8'; 'cell9'; 'cell10'; 'cell11'; 'cell12'};
            app.VoltageTable.RowName = {'IC1,IC2,IC3,IC4,IC5,IC6,IC7,IC8,IC9,IC10,IC11,IC12'; ''};
            app.TempTable = uitable(app.PrimaryTab);
            app.TempTable.ColumnName = {'Τ1'; 'Τ2'; 'Τ3'; 'Τ4'; 'Τ5'; 'Τ6'; 'Τ7'; 'Τ8'; 'Τ9'; 'Τ10'; 'Τ11'; 'Τ12'};
            app.TempTable.RowName = {'1:12'; ''};
            app.TempTable.CellEditCallback = createCallbackFcn(app, @TempTableCellEdit, true);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'SummaryTab';
            app.SegmentTable = uitable(app.SummaryTab);
            app.SegmentTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; ''; ''; ''; ''; ''; ''; ''; ''};
            app.SegmentTable.RowName = {};
            app.ICTempTable = uitable(app.SummaryTab);
            app.ICTempTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; ''; ''; ''; ''; ''; ''; ''; ''};
            app.ICTempTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bmsMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn3)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end